classdef IlastikValidator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImportImageTab                  matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        LoadcsvfilesButton              matlab.ui.control.Button
        LoadImagesButton                matlab.ui.control.Button
        csvimagesnamesconverterPanel    matlab.ui.container.Panel
        pretextEditField                matlab.ui.control.EditField
        posttextEditField               matlab.ui.control.EditField
        ImagefileLampLabel              matlab.ui.control.Label
        ImagefileLamp                   matlab.ui.control.Lamp
        ImagetypeEditFieldLabel         matlab.ui.control.Label
        ImagetypeEditField              matlab.ui.control.EditField
        preEditField_2Label             matlab.ui.control.Label
        preEditField                    matlab.ui.control.NumericEditField
        postEditFieldLabel              matlab.ui.control.Label
        postEditField                   matlab.ui.control.NumericEditField
        ReloadtableButton               matlab.ui.control.Button
        DatatableemptyLabel             matlab.ui.control.Label
        SamplenameidentifierPanel       matlab.ui.container.Panel
        prefixEditFieldLabel            matlab.ui.control.Label
        prefixEditField                 matlab.ui.control.EditField
        postfixEditFieldLabel           matlab.ui.control.Label
        postfixEditField                matlab.ui.control.EditField
        RegexLabel                      matlab.ui.control.Label
        RegexString                     matlab.ui.control.Label
        ValidateTab                     matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        startexperimentButton           matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        group1EditFieldLabel            matlab.ui.control.Label
        group1EditField                 matlab.ui.control.NumericEditField
        group2EditFieldLabel            matlab.ui.control.Label
        group2EditField                 matlab.ui.control.NumericEditField
        group3EditFieldLabel            matlab.ui.control.Label
        group3EditField                 matlab.ui.control.NumericEditField
        group4EditFieldLabel            matlab.ui.control.Label
        group4EditField                 matlab.ui.control.NumericEditField
        AutoupdatemainCheckBox          matlab.ui.control.CheckBox
        counterLabel                    matlab.ui.control.Label
        ClassestoincludeListBoxLabel    matlab.ui.control.Label
        ClassestoincludeListBox         matlab.ui.control.ListBox
        nextButton                      matlab.ui.control.Button
        PredictedLabel                  matlab.ui.control.Label
        ActualLabel                     matlab.ui.control.Label
        ImageperbatchEditFieldLabel     matlab.ui.control.Label
        ImageperbatchEditField          matlab.ui.control.NumericEditField
        BatchperclassEditFieldLabel     matlab.ui.control.Label
        BatchperclassEditField          matlab.ui.control.NumericEditField
        TextArea                        matlab.ui.control.TextArea
        exporttextandconfusionmatrixButton  matlab.ui.control.Button
        wheretoplotButtonGroup          matlab.ui.container.ButtonGroup
        internalButton                  matlab.ui.control.RadioButton
        externalButton                  matlab.ui.control.RadioButton
        ExporttableButton_2             matlab.ui.control.Button
        ImageExportTab                  matlab.ui.container.Tab
        plotsamplecategoryButton        matlab.ui.control.Button
        BarplotButton                   matlab.ui.control.Button
        CatagoriesDropDownLabel         matlab.ui.control.Label
        CatagoriesDropDown              matlab.ui.control.DropDown
        UIAxesbar                       matlab.ui.control.UIAxes
        UITableClassification           matlab.ui.control.Table
        SampleDropDownLabel             matlab.ui.control.Label
        SampleDropDown                  matlab.ui.control.DropDown
        numberofimagesLabel             matlab.ui.control.Label
        numberofimagesEditField         matlab.ui.control.NumericEditField
        ExporttableButton               matlab.ui.control.Button
        FilterTab                       matlab.ui.container.Tab
        UITableFilter                   matlab.ui.control.Table
        TextArea_2Label                 matlab.ui.control.Label
        TextAreaFilter                  matlab.ui.control.TextArea
        ValueEditFieldLabel             matlab.ui.control.Label
        FilterValueEditField            matlab.ui.control.NumericEditField
        filtertypeDropDownLabel         matlab.ui.control.Label
        filtertypeDropDown              matlab.ui.control.DropDown
        filterButton                    matlab.ui.control.Button
        plotsamplecategoryFilteredButton  matlab.ui.control.Button
        CatagoriesDropDown_2Label       matlab.ui.control.Label
        CatagoriesDropDown_filter       matlab.ui.control.DropDown
        SampleDropDown_2Label           matlab.ui.control.Label
        SampleDropDown_filter           matlab.ui.control.DropDown
        numberofimagesEditField_2Label  matlab.ui.control.Label
        numberofimagesEditField_2       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        file % filename or cellarray of file name
        path % file path
        Imagefiles % cell array of image file names
        CurrentImage = 1 %the image number to display
        ImageData %store the image data
        DataTable %datatable to store the imported data can be modified using the filter
        DataTableRaw % copy of the raw imported data table to be used with filters.
        importflag = false %indicate whether the csv files has already been imported
        ImageArray % mostly for testing the montage function
        regularexpresion = 'data-(\S+)_'
        testfile % example filename
        startend = [14, 9]  % index for common name convention, work if export names hasn't been modified in ilastik
        multifileLoad = false
        csvtoimage = false
        Classes % cell array of classes included in the csv files
        SelectedClasses %selected classes for the validation
        ValClass = 1% class curently being validated
        NumberOfImages = 100 % number of images used in a single validation step
        Confusionmatrix % for describing the confusion matrix
        BatchPerClass
        Counter % Counter for validation experiment
        AccuracyMeasure % Description
        AccuracyCellArray % Description
        lastclassimaged = 0 % counter for the validator image generator
        data_sub % subset of the data used for the validation to avoid revalidating the same images
        Samples % name of different samples
        ClassificationTable %table for dividing the classifications
        DiscardedSamples % table for the discarded samples from the filter tab
    end
    
    methods (Access = private)
        
        function Image_to_plot = CutImage(~,DataLine,imagedata)
            %disp([DataLine.BoundingBoxMinimum_1,DataLine.BoundingBoxMaximum_1,DataLine.BoundingBoxMinimum_0,DataLine.BoundingBoxMaximum_0])
            Image_to_plot = imagedata(DataLine.BoundingBoxMinimum_1+1:DataLine.BoundingBoxMaximum_1,DataLine.BoundingBoxMinimum_0+1:DataLine.BoundingBoxMaximum_0);   
        end
        
        function Image_to_array = CutImage_2_array(~,DataLine,imagedata,largest_x,largest_y,depth)
            imageRaw = imagedata(DataLine.BoundingBoxMinimum_1+1:DataLine.BoundingBoxMaximum_1,DataLine.BoundingBoxMinimum_0+1:DataLine.BoundingBoxMaximum_0,1:depth);
            imagepadded = zeros(largest_x,largest_y,depth);
            [img_x, img_y] = size(imageRaw);
            x_start = floor(0.5*(largest_x-img_x));
            y_start = floor(0.5*(largest_y-img_y));
            imagepadded(x_start:(x_start+img_x-1),y_start:(y_start+img_y-1),:)=imageRaw;
            Image_to_array = imagepadded;
        end
        
        function plot_the_image(~,h,image_to_plot)
            imagesc(h,image_to_plot);
            h.Visible = 'off';
            h.DataAspectRatio = [1,1,1];
            [~,~,depth] = size(image_to_plot);
            %disp(depth)
            if depth == 1
                h.Colormap = gray;
            end
        end
        
        %function that find the common part of two file names spits out a 1x2 matrix with the first
        %number being counted from the start and the second one from the
        %end, everything between those two number are differnet in the file
        %names
        function startend = commonnames(~,cell1,cell2)
            if iscell(cell1)
                cell1 = cell2mat(cell1);
            end
            if iscell(cell2)
                cell2 = cell2mat(cell2);
            end
            commonLen = min(length(cell1),length(cell2));
            lodicalarrayfwd = ~(cell1(1:commonLen) == cell2(1:commonLen));
            lodicalarraybck = ~(cell1(end-(commonLen-1):end) == cell2(end-(commonLen-1):end));
            startend = [find(lodicalarrayfwd,1)-1,((commonLen-1)-find(lodicalarraybck,1,'last'))];
        end
        
        function check_image_filename(app)
            first = app.preEditField.Value + 1;
            last = app.postEditField.Value + 1;
            imagefiles = {};
            if app.multifileLoad
                nr_files = length(app.file);
                for j=1:nr_files
                    singlefile = cell2mat(app.file(j));
                    imagefiles(j) = cellstr([singlefile(first:end-last) '.' app.ImagetypeEditField.Value]);
                end
            else
                nr_files = 1;
                imagefiles(1) = cellstr([app.file(first:end-last) '.' app.ImagetypeEditField.Value]);
            end
            
            FileExistLogical = zeros(nr_files,1);
            for k=1:nr_files
                FileExistLogical(k) = isfile([app.path cell2mat(imagefiles(k))]);
            end
            
            if mean(FileExistLogical) == 1
                app.ImagefileLamp.Color = [0 1 0];
                app.Imagefiles = imagefiles;
                app.LoadImagesButton.Enable = true;
                app.csvtoimage = true;
            else 
                app.ImagefileLamp.Color = [1 0 0];
                app.csvtoimage = false;
            end
        end


        function flag = import_csv_2_table(app)
            %load the table file if the image files exist
            warning('off','MATLAB:table:ModifiedAndSavedVarnames');
            app.DatatableemptyLabel.Visible = 1;
            if app.ImagefileLamp.Color(2) == 1
                %disp('importing csv files')
                for k=1:length(app.file)
                    % take into account if there is only one file
                    if app.multifileLoad
                        csvdata = readtable([app.path,cell2mat(app.file(k))]);
                        expToken = regexp(app.file(k),app.regularexpresion,'tokens');
                    else
                        csvdata = readtable([app.path,app.file]);
                        expToken = regexp(app.file,app.regularexpresion,'tokens');
                    end
                    csvdata.ImageIndex = repmat(k,height(csvdata),1);
                    

                    if ~isempty(expToken)
                        cellname = expToken{1};
                    else
                        cellname = {"All"};
                    end
                    csvdata.Sample = string(repmat(cellname,height(csvdata),1));
                    if ismember('UserLabel',csvdata.Properties.VariableNames)
                        csvdata = removevars(csvdata,'UserLabel');
                    end
                    if k ~= 1
                        app.DataTable = vertcat(app.DataTable, csvdata);
                    else
                        app.DataTable = csvdata;
                    end
                end
                flag = true;
                app.DatatableemptyLabel.Text = 'csv have been loaded into tables';
                %saves a copy of the datatable to be used with filters.
                app.DataTableRaw = app.DataTable;
                %assignin("base","csvdata",app.DataTable)
            else
                app.DatatableemptyLabel.Text = 'csv not imported into table, see reload button tooltip for info';
                app.ReloadtableButton.Visible = 1;
                app.ReloadtableButton.Enable = 1;
                flag = false;
            end
        end


        function UpdateFilenameFields(app,startend)
            app.preEditField.Value = min(nonzeros(startend(:,1)));
            app.postEditField.Value = min(nonzeros(startend(:,2)));
            app.pretextEditField.Value = app.testfile(1:nonzeros(startend(:,1)));
            app.posttextEditField.Value = app.testfile(end-nonzeros(startend(:,2)):end);
            
        end
        
        function AutoUpdateNumbers(app)
            if app.AutoupdatemainCheckBox.Value
                switch app.ValClass
                    case 1
                        app.group1EditField.Value = app.NumberOfImages-(app.group2EditField.Value+app.group3EditField.Value+app.group4EditField.Value);
                    case 2
                        app.group2EditField.Value = app.NumberOfImages-(app.group1EditField.Value+app.group3EditField.Value+app.group4EditField.Value);
                    case 3
                        app.group3EditField.Value = app.NumberOfImages-(app.group2EditField.Value+app.group1EditField.Value+app.group4EditField.Value);
                    case 4
                        app.group4EditField.Value = app.NumberOfImages-(app.group2EditField.Value+app.group3EditField.Value+app.group1EditField.Value);
                end
            end
        end
        
        function Finish_and_calulate_accurancy(app)
            app.TextArea.Enable = 1;
            % create the container for the accuray measurements the order
            % for each class is precision, recall/Sensitivity , specificity
            % and the last two element are average precision and
            % sensitivity/recall
            numofcla = length(app.SelectedClasses);
            app.AccuracyMeasure=zeros(length(app.SelectedClasses)*3+2,1);
            app.AccuracyCellArray = cell(numofcla*4+4,1);
            avgPrec = 0;
            avgRec = 0;
            SelectionVector = cumsum(ones(1,length(app.SelectedClasses)));
            for j=1:length(app.SelectedClasses)
                %calculate
                app.AccuracyMeasure(1+3*(j-1)) = app.Confusionmatrix(j,j)/sum(app.Confusionmatrix(:,j));
                app.AccuracyMeasure(2+3*(j-1)) = app.Confusionmatrix(j,j)/sum(app.Confusionmatrix(j,:));
                specifictySelect = SelectionVector(SelectionVector ~= j);
                app.AccuracyMeasure(3+3*(j-1)) = sum(app.Confusionmatrix(specifictySelect,specifictySelect),'all')/...
                                                 sum(app.Confusionmatrix(specifictySelect,:),'all');
                avgPrec = avgPrec + app.AccuracyMeasure(1+3*(j-1)) * sum(app.Confusionmatrix(j,:));
                avgRec = avgRec + app.AccuracyMeasure(2+3*(j-1)) * sum(app.Confusionmatrix(j,:));
                %assign
                app.AccuracyCellArray(1+4*(j-1)) = app.SelectedClasses(j);
                app.AccuracyCellArray{2+4*(j-1)} = ['precision: ' num2str(app.AccuracyMeasure(1+3*(j-1)),4)];
                app.AccuracyCellArray{3+4*(j-1)} = ['Recall/Sensitivity: ' num2str(app.AccuracyMeasure(2+3*(j-1)),4)];
                app.AccuracyCellArray{4+4*(j-1)} = ['Specificity: ' num2str(app.AccuracyMeasure(3+3*(j-1)),4)];
            end
            app.AccuracyMeasure(end-1) = avgPrec/sum(app.Confusionmatrix,'all');
            app.AccuracyMeasure(end) = avgRec/sum(app.Confusionmatrix,'all');
            app.AccuracyCellArray{end-3} = 'Average Precision';
            app.AccuracyCellArray{end-2} = num2str(app.AccuracyMeasure(end-1),8);
            app.AccuracyCellArray{end-1} = 'Average Recall';
            app.AccuracyCellArray{end} = num2str(app.AccuracyMeasure(end),8);
            app.TextArea.Visible = 1;
            assignin("base",'text',app.AccuracyCellArray)
            assignin('base','realtext',app.TextArea.Value)
%             disp(class(app.AccuracyCellArray))
%             app.AccuracyCellArray = convertStringsToChars(app.AccuracyCellArray);
            app.TextArea.Value = app.AccuracyCellArray';
            assignin("base",'acme',app.AccuracyMeasure)
        end
        
        function draw_validation_image(app, counter)
            %check whether a new class is being measured
            if app.lastclassimaged ~= app.ValClass
                data_one_catagorie = app.DataTable(strcmp(app.SelectedClasses(app.ValClass),app.DataTable.PredictedClass),:);
                if height(data_one_catagorie) < app.NumberOfImages*app.BatchPerClass
                    disp('error not enough images for that many validation images')
                    %consdier more error message
                end
            ImagesNeededInAllBatch= app.NumberOfImages*app.BatchPerClass;                
            indexes=randperm(height(data_one_catagorie),ImagesNeededInAllBatch);
            app.data_sub=data_one_catagorie(indexes,:);
            app.lastclassimaged = app.ValClass;
            end
            
            %findout what data to show
            ThisCount = mod(counter(1),app.BatchPerClass);
            if ThisCount ~= 0
                show_the_data(app,app.data_sub(1+app.NumberOfImages*(ThisCount-1):app.NumberOfImages*(ThisCount),:),2,app.SelectedClasses(app.ValClass));
            else
                show_the_data(app,app.data_sub(end-app.NumberOfImages+1:end,:),2,app.SelectedClasses(app.ValClass));
            end
            app.lastclassimaged = app.ValClass;
            %reset the counters
            switch app.ValClass
                case 1
                    app.group1EditField.Value = app.NumberOfImages;
                    app.group2EditField.Value = 0;
                    app.group3EditField.Value = 0;
                    app.group4EditField.Value = 0;
                case 2
                    app.group1EditField.Value = 0;
                    app.group2EditField.Value = app.NumberOfImages;
                    app.group3EditField.Value = 0;
                    app.group4EditField.Value = 0;
                case 3
                    app.group1EditField.Value = 0;
                    app.group2EditField.Value = 0;
                    app.group3EditField.Value = app.NumberOfImages;
                    app.group4EditField.Value = 0;
                case 4
                    app.group1EditField.Value = 0;
                    app.group2EditField.Value = 0;
                    app.group3EditField.Value = 0;
                    app.group4EditField.Value = app.NumberOfImages;
            end
        end
        
        function show_the_data(app,data_subset,figurenumber,title_to_show)
            
            imagenr = data_subset.ImageIndex(1);
            image = imread([app.path cell2mat(app.Imagefiles(imagenr))]);
            valuedepth = size(image,3);
            numbers=height(data_subset);
            largest_sub_image_x = max(data_subset.BoundingBoxMaximum_1-data_subset.BoundingBoxMinimum_1)+3;
            largest_sub_image_y = max(data_subset.BoundingBoxMaximum_0-data_subset.BoundingBoxMinimum_0)+3;
            ImageArray = zeros(largest_sub_image_x,largest_sub_image_y,valuedepth,numbers);
            for j=1:numbers

                if imagenr ~= data_subset.ImageIndex(j)
                    imagenr = data_subset.ImageIndex(j);
                    image = imread([app.path cell2mat(app.Imagefiles(imagenr))]);
                end
                ImageArray(:,:,:,j) = CutImage_2_array(app,data_subset(j,:),image,largest_sub_image_x,largest_sub_image_y,valuedepth);
            end
            
            app.ImageArray = ImageArray;
            %can't make it work with the UIaxes
            figure(figurenumber);
            clf;
%             if app.externalButton.Value
%                 figure(2);
%                 clf
%             else
%                 app.UIAxesbar;
%             end
            montage(app.ImageArray,'DisplayRange',[min(nonzeros(app.ImageArray),[],'all') max(nonzeros(app.ImageArray),[],'all')])
            title(gca,title_to_show)
        end
        
        function make_class_sample_table(app)
            app.Samples=unique(app.DataTable.Sample);
            app.ClassificationTable = zeros(length(app.Samples),length(app.Classes));
            for r = 1:length(app.Samples)
                for c = 1:length(app.Classes)
                    app.ClassificationTable(r,c) = sum(strcmp(app.DataTable.Sample, app.Samples(r)) & strcmp(app.DataTable.PredictedClass, app.Classes(c)));
                end
            end
            app.UITableClassification.ColumnName = app.Classes;
            app.UITableClassification.RowName = app.Samples;
            app.UITableClassification.Data = app.ClassificationTable;
            %if the filter table is empty fill out the first line (only
            %meant to run during first run of this fuction)
            if strcmp(app.UITableFilter.ColumnName(1),'unfilled')
               app.UITableFilter.ColumnName = app.Classes;
               app.UITableFilter.RowName = {'Raw'};
               app.UITableFilter.Data = sum(app.ClassificationTable);
            end
                
        end
        
        function count_prediction(app, newdatatable, rowname)
            PredClassSum = zeros(1,length(app.Classes));
            for c = 1:length(app.Classes)
                PredClassSum(c) = sum(strcmp(newdatatable.PredictedClass, app.Classes(c)));
            end
            app.UITableFilter.Data = [app.UITableFilter.Data; PredClassSum];
            app.UITableFilter.RowName = [app.UITableFilter.RowName; rowname];
        end
            
        
        function UpdateRegex(app)
            pre = app.prefixEditField.Value;
            post = app.postfixEditField.Value;
            app.regularexpresion = [pre, '(\S+)' , post];
            app.RegexString.Text = app.regularexpresion;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadcsvfilesButton
        function LoadcsvfilesButtonPushed(app, event)
            %get the csv files
            [app.file,app.path,idx] = uigetfile('*.csv','Select all the applicable csv files','MultiSelect','on');
            if idx == 0
                return
            end
            app.importflag = false;
            %generate a start guess for the image file name
            if iscell(app.file)
                app.multifileLoad = true;
                app.testfile = cell2mat(app.file(1));
            else
                app.testfile = app.file;
            end
            app.startend = [14,9];
            UpdateFilenameFields(app,app.startend)
            check_image_filename(app);
            
            % try to use the common name as an indicator for what the
            % numbers might be
            if ~app.csvtoimage && iscell(app.file)
                app.startend = zeros(length(app.file),2);
                for j=1:length(app.file)
                    
                    if ~(j == (length(app.file)-j+1))
                        % to avoid checking the name with itself
                        app.startend(j,:)=commonnames(app,app.file(j),app.file(end-j+1));
                    end
                end
                UpdateFilenameFields(app,app.startend);
                check_image_filename(app);
            end
            
            % if doesn't work check if the sample is in the end
            if ~app.csvtoimage
                SampleLength = strlength(regexp(app.testfile,app.regularexpresion,'match'));
                tempstartend = [app.preEditField.Value,app.postEditField.Value];
                app.startend = [tempstartend(1,1),tempstartend(1,2)+SampleLength];
                UpdateFilenameFields(app,app.startend);
                check_image_filename(app);
            end
            
             % if doesn't work check if the sample is in the begining
            if ~app.csvtoimage
                SampleLength = strlength(regexp(app.testfile,app.regularexpresion,'match'));
                tempstartend = [app.preEditField.Value,app.postEditField.Value];
                app.startend = [tempstartend(1,1)+SampleLength,tempstartend(1,2)];
                UpdateFilenameFields(app,app.startend);
                check_image_filename(app);
            end
            
            %if still doesn't work reset the values
            if ~app.csvtoimage
                app.startend = [14,9];
                UpdateFilenameFields(app,app.startend);
            end
            
            % check whether the data table has already been loaded
            % Is that even nessesary?
            if ~app.importflag
                app.importflag = import_csv_2_table(app);
            end
            
            %finsih up the import and extrat values for classifing the
            %samples
            if app.importflag
                app.Classes = unique(app.DataTable.PredictedClass);
                app.CatagoriesDropDown.Items = app.Classes;
                app.CatagoriesDropDown_filter.Items = app.Classes;
                app.ClassestoincludeListBox.Items = app.Classes;
                app.ClassestoincludeListBox.Value = app.Classes;
                make_class_sample_table(app)
                app.SampleDropDown.Items = unique(app.DataTable.Sample);
                app.SampleDropDown_filter.Items = [unique(app.DataTable.Sample);{'all'}];
            end
            %assignin('base',"data",app.DataTable);
            
        end

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            %load first image
            Image1 = imread([app.path cell2mat(app.Imagefiles(1))]);
            plot_the_image(app,app.UIAxes,Image1)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.CurrentImage = app.CurrentImage +1;
            if app.CurrentImage > length(app.Imagefiles)
                app.CurrentImage = 1;
            end
            Image1 = imread([app.path cell2mat(app.Imagefiles(app.CurrentImage))]);
            plot_the_image(app,app.UIAxes,Image1)
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.CurrentImage = app.CurrentImage -1;
            if app.CurrentImage < 1
                app.CurrentImage = length(app.Imagefiles);
            end
            Image1 = imread([app.path cell2mat(app.Imagefiles(app.CurrentImage))]);
            plot_the_image(app,app.UIAxes,Image1)
        end

        % Button pushed function: plotsamplecategoryButton
        function plotsamplecategoryButtonPushed(app, event)
            %generate the data
            investigatedSample = app.SampleDropDown.Value;
            investigatedClass = app.CatagoriesDropDown.Value;
            num = app.numberofimagesEditField.Value;
            sub_data=app.DataTable(strcmp(app.DataTable.Sample, investigatedSample) & strcmp(app.DataTable.PredictedClass,investigatedClass),:);
            
            %draw out only the needed numbers random
            if height(sub_data) > num
                indexes=randperm(height(sub_data),num);
                sub_data=sub_data(indexes,:);
            end
            new_title = ['Sample: ' investigatedSample '   predicted as ' investigatedClass];
            show_the_data(app,sub_data,4,new_title)
            
        end

        % Button pushed function: BarplotButton
        function BarplotButtonPushed(app, event)
            if length(app.Samples) == 1
                categorical_data = categorical(app.DataTable.PredictedClass);
                bar(app.UIAxesbar,unique(categorical_data),countcats(categorical_data),...
                        'FaceColor',[0 .5 .5],'EdgeColor',[0 .9 .9],'LineWidth',1.5);
            else
                bar(app.UIAxesbar,categorical(app.Classes),app.ClassificationTable');
                xlabel(app.UIAxesbar,'Predicted Class');
                ylabel(app.UIAxesbar,'Count')
                legend(app.UIAxesbar,app.Samples,'Location','eastoutside')
            end
        end

        % Button pushed function: ReloadtableButton
        function ReloadtableButtonPushed(app, event)
            check_image_filename(app);
            if ~app.importflag
                app.importflag = import_csv_2_table(app);
            end
            if app.importflag
                app.Classes = unique(app.DataTable.PredictedClass);
                app.CatagoriesDropDown.Items = app.Classes;
                app.CatagoriesDropDown_filter.Items = app.Classes;
                app.ClassestoincludeListBox.Items = app.Classes;
                app.ClassestoincludeListBox.Value = app.Classes;
                make_class_sample_table(app)
                app.SampleDropDown.Items = unique(app.DataTable.Sample);
                app.SampleDropDown_filter.Items = [unique(app.DataTable.Sample);{'all'}];
            end
            %assignin('base',"data",app.DataTable);
        end

        % Value changed function: preEditField
        function preEditFieldValueChanged(app, event)
            app.startend = [app.preEditField.Value,app.postEditField.Value];
            UpdateFilenameFields(app,app.startend);
            check_image_filename(app)
        end

        % Value changed function: postEditField
        function postEditFieldValueChanged(app, event)
            app.startend = [app.preEditField.Value,app.postEditField.Value];
            UpdateFilenameFields(app,app.startend);
            check_image_filename(app)
        end

        % Value changed function: pretextEditField
        function pretextEditFieldValueChanged(app, event)
            app.startend = [strlength(app.pretextEditField.Value),strlength(app.posttextEditField.Value)+1];
            UpdateFilenameFields(app,app.startend);
            check_image_filename(app)
            
        end

        % Value changed function: posttextEditField
        function posttextEditFieldValueChanged(app, event)
            app.startend = [strlength(app.pretextEditField.Value),strlength(app.posttextEditField.Value)-1];
            UpdateFilenameFields(app,app.startend);
            check_image_filename(app)
        end

        % Button pushed function: startexperimentButton
        function startexperimentButtonPushed(app, event)
            app.nextButton.Enable = 1;
            app.nextButton.Text = 'next';
            app.NumberOfImages = app.ImageperbatchEditField.Value;
            app.BatchPerClass = app.BatchperclassEditField.Value;
            
            
            app.ValClass = 1;
            app.SelectedClasses = app.ClassestoincludeListBox.Value;
            for i =1:length(app.SelectedClasses)*2
                element = app.Panel.Children(end+1-i);
                element.Visible = 1;
                if mod(i,2) == 1
                    element.Text = app.SelectedClasses(ceil(i/2));
                end
            end
            app.Counter = [1 app.BatchPerClass*length(app.SelectedClasses)];
            app.counterLabel.Text = ['number: ' num2str(app.Counter(1)) 'of ' num2str(app.Counter(2))];
            app.UITable.ColumnName = app.SelectedClasses;
            app.UITable.RowName = app.SelectedClasses;
            app.Confusionmatrix = zeros(length(app.SelectedClasses));
            app.UITable.Data = app.Confusionmatrix;
            app.lastclassimaged = 0;
            %here needs ot go code that check the length of all classes and
            %ensure there is enough + propper errormessage!
            draw_validation_image(app,app.Counter);
        end

        % Value changed function: group1EditField
        function group1EditFieldValueChanged(app, event)
            AutoUpdateNumbers(app)
        end

        % Value changed function: group2EditField
        function group2EditFieldValueChanged(app, event)
            AutoUpdateNumbers(app)
                       
        end

        % Value changed function: group3EditField
        function group3EditFieldValueChanged(app, event)
            AutoUpdateNumbers(app)
            
        end

        % Value changed function: group4EditField
        function group4EditFieldValueChanged(app, event)
            AutoUpdateNumbers(app)
            
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            % update confusion matrix
            Values = [app.group1EditField.Value, app.group2EditField.Value, app.group3EditField.Value,app.group4EditField.Value]';
            app.Confusionmatrix(:,app.ValClass) = app.Confusionmatrix(:,app.ValClass) + Values(1:length(app.SelectedClasses));
            app.UITable.Data = app.Confusionmatrix;
            
            % check if we are done yet
            if app.Counter(1) == app.Counter(2)
                app.nextButton.Enable = 0;
                Finish_and_calulate_accurancy(app)
            else
                %update counters and more
                if app.ValClass*app.BatchPerClass == app.Counter(1)
                    app.ValClass = app.ValClass + 1;
                end
                app.Counter(1) = app.Counter(1) + 1;
                draw_validation_image(app,app.Counter);
            end
            app.counterLabel.Text = ['number: ' num2str(app.Counter(1)) 'of ' num2str(app.Counter(2))];
            if app.Counter(1) == app.Counter(2)
                app.nextButton.Text = 'Finish';
            end
        end

        % Button pushed function: filterButton
        function filterButtonPushed(app, event)
            PredictionResult = sort(table2array(app.DataTableRaw(:,5:7)),2,'descend');
            switch app.filtertypeDropDown.Value
                case 'A'
                    SelectionVector = PredictionResult(:,1) >= app.FilterValueEditField.Value;
                case 'B'
                    SelectionVector = (PredictionResult(:,1) - PredictionResult(:,2)) >= app.FilterValueEditField.Value;
            end
            if sum(~SelectionVector,'all') > 0
               app.DiscardedSamples = app.DataTableRaw(~SelectionVector,:);
            else
                app.DiscardedSamples = [];
            end
            app.DataTable = app.DataTableRaw(SelectionVector,:);
            filtername = [app.filtertypeDropDown.Value, ' ', num2str(app.FilterValueEditField.Value)];
            count_prediction(app,app.DataTable,filtername);
            make_class_sample_table(app)
            
            %prepare the filter images elements
            
        end

        % Button pushed function: plotsamplecategoryFilteredButton
        function plotsamplecategoryFilteredButtonPushed(app, event)
            %generate the data
            investigatedSample = app.SampleDropDown_filter.Value;
            investigatedClass = app.CatagoriesDropDown_filter.Value;
            num = app.numberofimagesEditField_2.Value;
            if ~isempty(app.DiscardedSamples)
                if ~strcmp(investigatedSample,'all')
                    sub_data=app.DiscardedSamples(strcmp(app.DiscardedSamples.Sample, investigatedSample) & strcmp(app.DiscardedSamples.PredictedClass,investigatedClass),:);
                else
                    sub_data=app.DiscardedSamples(strcmp(app.DiscardedSamples.PredictedClass,investigatedClass),:);
                end
            else
                msgbox('No objects have been filtered, click the filter button first','No images to show', 'warn')
                return
            end
            
            if isempty(sub_data)
                errormsg = join(['no filtered object of ' , investigatedClass , ' in sample ' , investigatedSample]);
                msgbox(errormsg,'No images to show', 'help')
                return
            end

            %draw out only the needed numbers random
            if height(sub_data) > num
                indexes=randperm(height(sub_data),num);
                sub_data=sub_data(indexes,:);
            end
            new_title = ['Sample: ' investigatedSample '; Predicted as: ' investigatedClass '; Removed by filter: ' char(app.UITableFilter.RowName(end))];            
            %disp(new_title)
            show_the_data(app,sub_data,4,new_title)
        end

        % Value changed function: postfixEditField, prefixEditField
        function prefixEditFieldValueChanged(app, event)
            UpdateRegex(app)
            
        end

        % Button pushed function: ExporttableButton
        function ExporttableButtonPushed(app, event)
            t = array2table(app.UITableClassification.Data,"RowNames",app.UITableClassification.RowName,'VariableNames',app.UITableClassification.ColumnName);
            %assignin("base",'tablet',t)
            [file,path] = uiputfile('*.csv','Save the table');
            if file ~= 0
                writetable(t,[path,file],'WriteRowNames',true);
            end
            
        end

        % Button pushed function: ExporttableButton_2
        function ExporttableButton_2Pushed(app, event)
            t = array2table(app.UITable.Data,"RowNames",app.UITable.RowName,'VariableNames',app.UITable.ColumnName);
            %assignin("base",'tablet',t)
            [file,path] = uiputfile('*.csv','Save the table');
            if file ~= 0
                writetable(t,[path,file],'WriteRowNames',true);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportImageTab = uitab(app.TabGroup);
            app.ImportImageTab.Title = 'Import Image';
            app.UIAxes = uiaxes(app.ImportImageTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NextButton = uibutton(app.ImportImageTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = '<';
            app.PreviousButton = uibutton(app.ImportImageTab, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '>';
            app.LoadcsvfilesButton = uibutton(app.ImportImageTab, 'push');
            app.LoadcsvfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcsvfilesButtonPushed, true);
            app.LoadcsvfilesButton.Text = 'Load csv files';
            app.LoadImagesButton = uibutton(app.ImportImageTab, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Enable = 'off';
            app.LoadImagesButton.Tooltip = {'Load CSV files first'};
            app.LoadImagesButton.Text = 'Load Images';
            app.csvimagesnamesconverterPanel = uipanel(app.ImportImageTab);
            app.csvimagesnamesconverterPanel.Title = 'csv - > images names converter';
            app.pretextEditField = uieditfield(app.csvimagesnamesconverterPanel, 'text');
            app.pretextEditField.ValueChangedFcn = createCallbackFcn(app, @pretextEditFieldValueChanged, true);
            app.posttextEditField = uieditfield(app.csvimagesnamesconverterPanel, 'text');
            app.posttextEditField.ValueChangedFcn = createCallbackFcn(app, @posttextEditFieldValueChanged, true);
            app.ImagefileLampLabel = uilabel(app.csvimagesnamesconverterPanel);
            app.ImagefileLampLabel.Text = 'Image file';
            app.ImagefileLamp = uilamp(app.csvimagesnamesconverterPanel);
            app.ImagetypeEditFieldLabel = uilabel(app.csvimagesnamesconverterPanel);
            app.ImagetypeEditFieldLabel.Text = 'Image type';
            app.ImagetypeEditField = uieditfield(app.csvimagesnamesconverterPanel, 'text');
            app.ImagetypeEditField.Value = 'tif';
            app.preEditField_2Label = uilabel(app.csvimagesnamesconverterPanel);
            app.preEditField_2Label.Text = 'pre';
            app.preEditField = uieditfield(app.csvimagesnamesconverterPanel, 'numeric');
            app.preEditField.ValueChangedFcn = createCallbackFcn(app, @preEditFieldValueChanged, true);
            app.postEditFieldLabel = uilabel(app.csvimagesnamesconverterPanel);
            app.postEditFieldLabel.Text = 'post';
            app.postEditField = uieditfield(app.csvimagesnamesconverterPanel, 'numeric');
            app.postEditField.ValueChangedFcn = createCallbackFcn(app, @postEditFieldValueChanged, true);
            app.ReloadtableButton = uibutton(app.ImportImageTab, 'push');
            app.ReloadtableButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadtableButtonPushed, true);
            app.ReloadtableButton.Enable = 'off';
            app.ReloadtableButton.Visible = 'off';
            app.ReloadtableButton.Tooltip = {'if loading the csv into a table field, try reloading after chaning the name convert setting'};
            app.ReloadtableButton.Text = 'Reload table';
            app.DatatableemptyLabel = uilabel(app.ImportImageTab);
            app.DatatableemptyLabel.Text = 'Data table empty';
            app.SamplenameidentifierPanel = uipanel(app.ImportImageTab);
            app.SamplenameidentifierPanel.Title = 'Sample name identifier';
            app.prefixEditFieldLabel = uilabel(app.SamplenameidentifierPanel);
            app.prefixEditFieldLabel.Text = 'prefix';
            app.prefixEditField = uieditfield(app.SamplenameidentifierPanel, 'text');
            app.prefixEditField.ValueChangedFcn = createCallbackFcn(app, @prefixEditFieldValueChanged, true);
            app.prefixEditField.Value = 'data-';
            app.postfixEditFieldLabel = uilabel(app.SamplenameidentifierPanel);
            app.postfixEditFieldLabel.Text = 'postfix';
            app.postfixEditField = uieditfield(app.SamplenameidentifierPanel, 'text');
            app.postfixEditField.ValueChangedFcn = createCallbackFcn(app, @prefixEditFieldValueChanged, true);
            app.postfixEditField.Value = '_';
            app.RegexLabel = uilabel(app.SamplenameidentifierPanel);
            app.RegexLabel.Text = 'Regex:';
            app.RegexString = uilabel(app.SamplenameidentifierPanel);
            app.RegexString.Text = 'data-(\S+)_';
            app.ValidateTab = uitab(app.TabGroup);
            app.ValidateTab.Title = 'Validate';
            app.UITable = uitable(app.ValidateTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.startexperimentButton = uibutton(app.ValidateTab, 'push');
            app.startexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @startexperimentButtonPushed, true);
            app.startexperimentButton.Text = 'start experiment';
            app.Panel = uipanel(app.ValidateTab);
            app.Panel.Title = 'Panel';
            app.group1EditFieldLabel = uilabel(app.Panel);
            app.group1EditFieldLabel.Visible = 'off';
            app.group1EditFieldLabel.Text = 'group1';
            app.group1EditField = uieditfield(app.Panel, 'numeric');
            app.group1EditField.ValueChangedFcn = createCallbackFcn(app, @group1EditFieldValueChanged, true);
            app.group1EditField.Visible = 'off';
            app.group2EditFieldLabel = uilabel(app.Panel);
            app.group2EditFieldLabel.Visible = 'off';
            app.group2EditFieldLabel.Text = 'group2';
            app.group2EditField = uieditfield(app.Panel, 'numeric');
            app.group2EditField.ValueChangedFcn = createCallbackFcn(app, @group2EditFieldValueChanged, true);
            app.group2EditField.Visible = 'off';
            app.group3EditFieldLabel = uilabel(app.Panel);
            app.group3EditFieldLabel.Visible = 'off';
            app.group3EditFieldLabel.Text = 'group3';
            app.group3EditField = uieditfield(app.Panel, 'numeric');
            app.group3EditField.ValueChangedFcn = createCallbackFcn(app, @group3EditFieldValueChanged, true);
            app.group3EditField.Visible = 'off';
            app.group4EditFieldLabel = uilabel(app.Panel);
            app.group4EditFieldLabel.Visible = 'off';
            app.group4EditFieldLabel.Text = 'group4';
            app.group4EditField = uieditfield(app.Panel, 'numeric');
            app.group4EditField.ValueChangedFcn = createCallbackFcn(app, @group4EditFieldValueChanged, true);
            app.group4EditField.Visible = 'off';
            app.AutoupdatemainCheckBox = uicheckbox(app.Panel);
            app.AutoupdatemainCheckBox.Text = 'Auto update main';
            app.AutoupdatemainCheckBox.Value = true;
            app.counterLabel = uilabel(app.Panel);
            app.counterLabel.Text = 'number: x of yy';
            app.ClassestoincludeListBoxLabel = uilabel(app.ValidateTab);
            app.ClassestoincludeListBoxLabel.Text = 'Classes to include';
            app.ClassestoincludeListBox = uilistbox(app.ValidateTab);
            app.ClassestoincludeListBox.Multiselect = 'on';
            app.ClassestoincludeListBox.Value = {'Item 1'};
            app.nextButton = uibutton(app.ValidateTab, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Enable = 'off';
            app.nextButton.Text = 'next';
            app.PredictedLabel = uilabel(app.ValidateTab);
            app.PredictedLabel.Text = 'Predicted';
            app.ActualLabel = uilabel(app.ValidateTab);
            app.ActualLabel.Text = 'Actual';
            app.ImageperbatchEditFieldLabel = uilabel(app.ValidateTab);
            app.ImageperbatchEditFieldLabel.Text = 'Image per batch';
            app.ImageperbatchEditField = uieditfield(app.ValidateTab, 'numeric');
            app.ImageperbatchEditField.Value = 100;
            app.BatchperclassEditFieldLabel = uilabel(app.ValidateTab);
            app.BatchperclassEditFieldLabel.Text = 'Batch per class';
            app.BatchperclassEditField = uieditfield(app.ValidateTab, 'numeric');
            app.BatchperclassEditField.Value = 1;
            app.TextArea = uitextarea(app.ValidateTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'her goes '; 'mme a'; ''; 'aogog'; 'noller'};
            app.exporttextandconfusionmatrixButton = uibutton(app.ValidateTab, 'push');
            app.exporttextandconfusionmatrixButton.Enable = 'off';
            app.exporttextandconfusionmatrixButton.Tooltip = {'to be implemented'};
            app.exporttextandconfusionmatrixButton.Text = 'export text and confusion matrix';
            app.wheretoplotButtonGroup = uibuttongroup(app.ValidateTab);
            app.wheretoplotButtonGroup.Title = 'where to plot';
            app.internalButton = uiradiobutton(app.wheretoplotButtonGroup);
            app.internalButton.Enable = 'off';
            app.internalButton.Tooltip = {'not implemented'};
            app.internalButton.Text = 'internal';
            app.externalButton = uiradiobutton(app.wheretoplotButtonGroup);
            app.externalButton.Text = 'external';
            app.externalButton.Value = true;
            app.ExporttableButton_2 = uibutton(app.ValidateTab, 'push');
            app.ExporttableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExporttableButton_2Pushed, true);
            app.ExporttableButton_2.Text = 'Export table';
            app.ImageExportTab = uitab(app.TabGroup);
            app.ImageExportTab.Title = 'Image Export';
            app.plotsamplecategoryButton = uibutton(app.ImageExportTab, 'push');
            app.plotsamplecategoryButton.ButtonPushedFcn = createCallbackFcn(app, @plotsamplecategoryButtonPushed, true);
            app.plotsamplecategoryButton.Text = {'plot'; ' sample '; '+ '; 'category'};
            app.BarplotButton = uibutton(app.ImageExportTab, 'push');
            app.BarplotButton.ButtonPushedFcn = createCallbackFcn(app, @BarplotButtonPushed, true);
            app.BarplotButton.Text = 'Barplot';
            app.CatagoriesDropDownLabel = uilabel(app.ImageExportTab);
            app.CatagoriesDropDownLabel.Text = 'Catagories';
            app.CatagoriesDropDown = uidropdown(app.ImageExportTab);
            app.UIAxesbar = uiaxes(app.ImageExportTab);
            title(app.UIAxesbar, 'Barplot')
            xlabel(app.UIAxesbar, 'X')
            ylabel(app.UIAxesbar, 'Y')
            app.UITableClassification = uitable(app.ImageExportTab);
            app.UITableClassification.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableClassification.RowName = {};
            app.SampleDropDownLabel = uilabel(app.ImageExportTab);
            app.SampleDropDownLabel.Text = 'Sample';
            app.SampleDropDown = uidropdown(app.ImageExportTab);
            app.numberofimagesLabel = uilabel(app.ImageExportTab);
            app.numberofimagesLabel.Text = 'number of images';
            app.numberofimagesEditField = uieditfield(app.ImageExportTab, 'numeric');
            app.numberofimagesEditField.Limits = [1 Inf];
            app.numberofimagesEditField.RoundFractionalValues = 'on';
            app.numberofimagesEditField.Value = 100;
            app.ExporttableButton = uibutton(app.ImageExportTab, 'push');
            app.ExporttableButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttableButtonPushed, true);
            app.ExporttableButton.Text = 'Export table';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.UITableFilter = uitable(app.FilterTab);
            app.UITableFilter.ColumnName = {'unfilled'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableFilter.RowName = {};
            app.TextArea_2Label = uilabel(app.FilterTab);
            app.TextArea_2Label.Text = 'Text Area';
            app.TextAreaFilter = uitextarea(app.FilterTab);
            app.TextAreaFilter.Editable = 'off';
            app.TextAreaFilter.Value = {'A: min value for prediction'; 'B: difference between prediction and next value'};
            app.ValueEditFieldLabel = uilabel(app.FilterTab);
            app.ValueEditFieldLabel.Text = 'Value';
            app.FilterValueEditField = uieditfield(app.FilterTab, 'numeric');
            app.FilterValueEditField.Limits = [0 1];
            app.FilterValueEditField.Value = 0.5;
            app.filtertypeDropDownLabel = uilabel(app.FilterTab);
            app.filtertypeDropDownLabel.Text = 'filter type';
            app.filtertypeDropDown = uidropdown(app.FilterTab);
            app.filtertypeDropDown.Items = {'A', 'B'};
            app.filtertypeDropDown.Value = 'A';
            app.filterButton = uibutton(app.FilterTab, 'push');
            app.filterButton.ButtonPushedFcn = createCallbackFcn(app, @filterButtonPushed, true);
            app.filterButton.Text = 'filter';
            app.plotsamplecategoryFilteredButton = uibutton(app.FilterTab, 'push');
            app.plotsamplecategoryFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @plotsamplecategoryFilteredButtonPushed, true);
            app.plotsamplecategoryFilteredButton.Text = {'Show discarded'; ' sample '; '+ '; 'category'};
            app.CatagoriesDropDown_2Label = uilabel(app.FilterTab);
            app.CatagoriesDropDown_2Label.Text = 'Catagories';
            app.CatagoriesDropDown_filter = uidropdown(app.FilterTab);
            app.SampleDropDown_2Label = uilabel(app.FilterTab);
            app.SampleDropDown_2Label.Text = 'Sample';
            app.SampleDropDown_filter = uidropdown(app.FilterTab);
            app.numberofimagesEditField_2Label = uilabel(app.FilterTab);
            app.numberofimagesEditField_2Label.Text = 'number of images';
            app.numberofimagesEditField_2 = uieditfield(app.FilterTab, 'numeric');
            app.numberofimagesEditField_2.Limits = [1 Inf];
            app.numberofimagesEditField_2.RoundFractionalValues = 'on';
            app.numberofimagesEditField_2.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IlastikValidator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end